classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab_3                         matlab.ui.container.Tab
        EditFieldLabel                matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Tab_4                         matlab.ui.container.Tab
        AlgorithmDropDownLabel        matlab.ui.control.Label
        AlgorithmDropDown             matlab.ui.control.DropDown
        InputChannelEditFieldLabel    matlab.ui.control.Label
        InputChannelEditField         matlab.ui.control.EditField
        OutputSeedNameEditFieldLabel  matlab.ui.control.Label
        OutputSeedNameEditField       matlab.ui.control.EditField
        SaveCurrentFigureButton       matlab.ui.control.Button
        Tab_2                         matlab.ui.container.Tab
        Tab_5                         matlab.ui.container.Tab
        Tab_6                         matlab.ui.container.Tab
        Tab_7                         matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        ImageSelectionPanel           matlab.ui.container.Panel
        RowDropDownLabel              matlab.ui.control.Label
        RowDropDown                   matlab.ui.control.DropDown
        ColumnDropDownLabel           matlab.ui.control.Label
        ColumnDropDown                matlab.ui.control.DropDown
        FieldDropDownLabel            matlab.ui.control.Label
        FieldDropDown                 matlab.ui.control.DropDown
        ExperimentDropDownLabel       matlab.ui.control.Label
        ExperimentDropDown            matlab.ui.control.DropDown
        TimepointDropDownLabel        matlab.ui.control.Label
        TimepointDropDown             matlab.ui.control.DropDown
        PlateDropDownLabel            matlab.ui.control.Label
        PlateDropDown                 matlab.ui.control.DropDown
        ImageSelectionLabel           matlab.ui.control.Label
        ChannelsLabel                 matlab.ui.control.Label
        SpotOverlayLabel              matlab.ui.control.Label
        Seed1NucleusPanel             matlab.ui.container.Panel
        CheckBox_4                    matlab.ui.control.CheckBox
        ColorLamp_4                   matlab.ui.control.Lamp
        Slider_4                      matlab.ui.control.Slider
        Label1NucleusPanel            matlab.ui.container.Panel
        CheckBox_5                    matlab.ui.control.CheckBox
        ColorLamp_5                   matlab.ui.control.Lamp
        Slider_5                      matlab.ui.control.Slider
        Label2CellPanel               matlab.ui.container.Panel
        CheckBox_6                    matlab.ui.control.CheckBox
        ColorLamp_6                   matlab.ui.control.Lamp
        Slider_6                      matlab.ui.control.Slider
        Label2MeasurementPanel        matlab.ui.container.Panel
        CheckBox_7                    matlab.ui.control.CheckBox
        ColorLamp_7                   matlab.ui.control.Lamp
        Slider_7                      matlab.ui.control.Slider
        Channel1ROSPanel_3            matlab.ui.container.Panel
        CheckBox_9                    matlab.ui.control.CheckBox
        LabelOverlay                  matlab.ui.control.Label
        MeasurementOverlayLabel       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Slider_9                      matlab.ui.control.Slider
        Button_3                      matlab.ui.control.Button
        Label_12                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        ColorButton                   matlab.ui.control.Button
        Button_5                      matlab.ui.control.Button
        ROSLabel                      matlab.ui.control.Label
        CheckBox_10                   matlab.ui.control.CheckBox
        Slider_11                     matlab.ui.control.Slider
        Button_6                      matlab.ui.control.Button
        HA2XLabel                     matlab.ui.control.Label
        CheckBox_11                   matlab.ui.control.CheckBox
        Slider_12                     matlab.ui.control.Slider
        Button_7                      matlab.ui.control.Button
        DAPILabel_3                   matlab.ui.control.Label
        CheckBox_12                   matlab.ui.control.CheckBox
        Slider_13                     matlab.ui.control.Slider
        Button_8                      matlab.ui.control.Button
        SELabel                       matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
        SegmentationLabel             matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        MadewithinTorontoLabel        matlab.ui.control.Label
        DanielSniderLabel             matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Button_9                      matlab.ui.control.Button
        MeasurementsLabel             matlab.ui.control.Label
        TrackingLabel                 matlab.ui.control.Label
        PlottingLabel                 matlab.ui.control.Label
        SpottingLabel                 matlab.ui.control.Label
        InputDataLabel                matlab.ui.control.Label
        Button_10                     matlab.ui.control.Button
        Button_11                     matlab.ui.control.Button
        Button_12                     matlab.ui.control.Button
        Button_13                     matlab.ui.control.Button
        Button_14                     matlab.ui.control.Button
        Button_15                     matlab.ui.control.Button
        Label_16                      matlab.ui.control.Label
    end


    properties (Access = public)
        img % currently displayed image
        spotting
    end

    methods (Access = public)
    
         function init_spotting(app)
            app.AlgorithmDropDown.Items = {'spotA', 'spotB'};
            app.AlgorithmDropDown.Value = 'spotA';
            app.AlgorithmDropDown.ValueChangedFcn(app, 'Update') % trigger once
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
         end
         
         function draw_global_figure_(app)
             draw_global_figure(app)
         end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, eu)
            set(0,'DefaultFigureWindowStyle','docked')
            addpath(genpath('functions'));
            app.img = imread('images/example_cells/r02c02f01p01-ch1sk1fk1fl1.tiff');
            app.spotting = {};
            
            app.TabGroup.SelectedTab = app.Tab_4;
            init_spotting(app);
        
         
%             imshow('LFS00012.jpg','Parent',app.UIAxes_2);
%             im = imread('LFS00012.jpg');
%             f = figure(2); imshow3D(im);
%             imgs_to_gif(im);
%             
%             dcm_obj = datacursormode(f);
%             set(dcm_obj, 'Enable', 'on', 'UpdateFcn', {@datatip})
%             
%             [x,y,button] = ginput(1)

        end

        % Callback function
        function UITableCellEdit(app, event)
            update_plot(app);
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            algo_name = app.AlgorithmDropDown.Value;
            eval(['definition_' algo_name]);
            draw(algo_name, params, app, @createCallbackFcn) % initialize dynamic GUI
            app.spotting.fields{1}.ValueChangedFcn(app, 'Update') % trigger once
        end

        % Callback function
        function ButtonPushed(app, event)
            c = uisetcolor([1 1 0],'Select a color')
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab_2;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '                          ';
            app.EditFieldLabel = uilabel(app.Tab_3);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab_3, 'text');
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '                          ';
            app.AlgorithmDropDownLabel = uilabel(app.Tab_4);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.Tab_4);
            app.AlgorithmDropDown.Items = {};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = {};
            app.InputChannelEditFieldLabel = uilabel(app.Tab_4);
            app.InputChannelEditFieldLabel.Text = 'Input Channel';
            app.InputChannelEditField = uieditfield(app.Tab_4, 'text');
            app.InputChannelEditField.Value = 'Nucleus';
            app.OutputSeedNameEditFieldLabel = uilabel(app.Tab_4);
            app.OutputSeedNameEditFieldLabel.Text = 'Output Seed Name';
            app.OutputSeedNameEditField = uieditfield(app.Tab_4, 'text');
            app.OutputSeedNameEditField.Value = 'Nucleus';
            app.SaveCurrentFigureButton = uibutton(app.Tab_4, 'push');
            app.SaveCurrentFigureButton.Text = 'Save Current Figure';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '                          ';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '                          ';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '                          ';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '                          ';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.ImageSelectionPanel = uipanel(app.Tab);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.RowDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.RowDropDownLabel.Text = 'Row';
            app.RowDropDown = uidropdown(app.ImageSelectionPanel);
            app.ColumnDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.ColumnDropDownLabel.Text = 'Column';
            app.ColumnDropDown = uidropdown(app.ImageSelectionPanel);
            app.FieldDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.ImageSelectionPanel);
            app.ExperimentDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.ImageSelectionPanel);
            app.TimepointDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.TimepointDropDownLabel.Text = 'Timepoint';
            app.TimepointDropDown = uidropdown(app.ImageSelectionPanel);
            app.PlateDropDownLabel = uilabel(app.ImageSelectionPanel);
            app.PlateDropDownLabel.Text = 'Plate';
            app.PlateDropDown = uidropdown(app.ImageSelectionPanel);
            app.ImageSelectionLabel = uilabel(app.Tab);
            app.ImageSelectionLabel.Text = 'Image Selection';
            app.ChannelsLabel = uilabel(app.Tab);
            app.ChannelsLabel.Text = 'Channels';
            app.SpotOverlayLabel = uilabel(app.Tab);
            app.SpotOverlayLabel.Text = 'Spot Overlay';
            app.Seed1NucleusPanel = uipanel(app.Tab);
            app.Seed1NucleusPanel.Title = 'Seed 1: Nucleus';
            app.CheckBox_4 = uicheckbox(app.Seed1NucleusPanel);
            app.CheckBox_4.Text = '';
            app.ColorLamp_4 = uilamp(app.Seed1NucleusPanel);
            app.Slider_4 = uislider(app.Seed1NucleusPanel);
            app.Slider_4.Limits = [0 50];
            app.Slider_4.MajorTickLabels = {'0', '25', '50'};
            app.Slider_4.MinorTicks = [];
            app.Label1NucleusPanel = uipanel(app.Tab);
            app.Label1NucleusPanel.Title = 'Label 1: Nucleus';
            app.CheckBox_5 = uicheckbox(app.Label1NucleusPanel);
            app.CheckBox_5.Text = '';
            app.ColorLamp_5 = uilamp(app.Label1NucleusPanel);
            app.Slider_5 = uislider(app.Label1NucleusPanel);
            app.Slider_5.Limits = [0 50];
            app.Slider_5.MajorTickLabels = {'0', '25', '50'};
            app.Slider_5.MinorTicks = [];
            app.Label2CellPanel = uipanel(app.Tab);
            app.Label2CellPanel.Title = 'Label 2: Cell';
            app.CheckBox_6 = uicheckbox(app.Label2CellPanel);
            app.CheckBox_6.Text = '';
            app.ColorLamp_6 = uilamp(app.Label2CellPanel);
            app.Slider_6 = uislider(app.Label2CellPanel);
            app.Slider_6.Limits = [0 50];
            app.Slider_6.MajorTickLabels = {'0', '25', '50'};
            app.Slider_6.MinorTicks = [];
            app.Label2MeasurementPanel = uipanel(app.Tab);
            app.Label2MeasurementPanel.Title = 'Label 2: Measurement';
            app.CheckBox_7 = uicheckbox(app.Label2MeasurementPanel);
            app.CheckBox_7.Text = '';
            app.ColorLamp_7 = uilamp(app.Label2MeasurementPanel);
            app.Slider_7 = uislider(app.Label2MeasurementPanel);
            app.Slider_7.Limits = [0 50];
            app.Slider_7.MajorTickLabels = {'0', '25', '50'};
            app.Slider_7.MinorTicks = [];
            app.Channel1ROSPanel_3 = uipanel(app.Tab);
            app.Channel1ROSPanel_3.Title = '                Channel 1: ROS';
            app.CheckBox_9 = uicheckbox(app.Tab);
            app.CheckBox_9.Text = '';
            app.LabelOverlay = uilabel(app.Tab);
            app.LabelOverlay.Text = 'Label Overlay';
            app.MeasurementOverlayLabel = uilabel(app.Tab);
            app.MeasurementOverlayLabel.Text = 'Measurement Overlay';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '⬤🆑';
            app.Slider_9 = uislider(app.Tab);
            app.Slider_9.MajorTicks = [];
            app.Slider_9.MajorTickLabels = {};
            app.Slider_9.MinorTicks = [];
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.Icon = 'camera.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Label_12 = uilabel(app.Tab);
            app.Label_12.Text = '📏';
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = '🏿';
            app.Label_15 = uilabel(app.Tab);
            app.Label_15.Text = ' 🎫';
            app.ColorButton = uibutton(app.Tab, 'push');
            app.ColorButton.Text = 'Color';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.Text = '🎨';
            app.ROSLabel = uilabel(app.Tab);
            app.ROSLabel.Text = 'ROS';
            app.CheckBox_10 = uicheckbox(app.Tab);
            app.CheckBox_10.Text = '';
            app.Slider_11 = uislider(app.Tab);
            app.Slider_11.MajorTicks = [];
            app.Slider_11.MajorTickLabels = {};
            app.Slider_11.MinorTicks = [];
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.Text = '🎨';
            app.HA2XLabel = uilabel(app.Tab);
            app.HA2XLabel.Text = 'HA2X';
            app.CheckBox_11 = uicheckbox(app.Tab);
            app.CheckBox_11.Text = '';
            app.Slider_12 = uislider(app.Tab);
            app.Slider_12.MajorTicks = [];
            app.Slider_12.MajorTickLabels = {};
            app.Slider_12.MinorTicks = [];
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.Text = '🎨';
            app.DAPILabel_3 = uilabel(app.Tab);
            app.DAPILabel_3.Text = 'DAPI';
            app.CheckBox_12 = uicheckbox(app.Tab);
            app.CheckBox_12.Text = '';
            app.Slider_13 = uislider(app.Tab);
            app.Slider_13.MajorTicks = [];
            app.Slider_13.MajorTickLabels = {};
            app.Slider_13.MinorTicks = [];
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.Text = '🎨';
            app.SELabel = uilabel(app.Tab);
            app.SELabel.Text = 'SE';
            app.Tab2 = uitab(app.TabGroup2);
            app.Tab2.Title = 'Tab2';
            app.SegmentationLabel = uilabel(app.UIFigure);
            app.SegmentationLabel.Text = 'Segmentation';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '↓';
            app.MadewithinTorontoLabel = uilabel(app.UIFigure);
            app.MadewithinTorontoLabel.Text = 'Made with 💛 in Toronto';
            app.DanielSniderLabel = uilabel(app.UIFigure);
            app.DanielSniderLabel.Text = '@DanielSnider ツ';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '📈';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '👁';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '📁';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '➠';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '📏';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '👁';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '🏿';
            app.MeasurementsLabel = uilabel(app.UIFigure);
            app.MeasurementsLabel.Text = 'Measurements';
            app.TrackingLabel = uilabel(app.UIFigure);
            app.TrackingLabel.Text = 'Tracking';
            app.PlottingLabel = uilabel(app.UIFigure);
            app.PlottingLabel.Text = 'Plotting';
            app.SpottingLabel = uilabel(app.UIFigure);
            app.SpottingLabel.Text = 'Spotting';
            app.InputDataLabel = uilabel(app.UIFigure);
            app.InputDataLabel.Text = 'Input Data';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '📁';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '🏿';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '👁';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.Text = '📈';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.Text = '➠';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.Text = '📏';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '🎴1️⃣ ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end